classdef icontact < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Image2_2    matlab.ui.control.Image
        Image2      matlab.ui.control.Image
        CSVButton   matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        UITable     matlab.ui.control.Table
        Button      matlab.ui.control.Button
        Image       matlab.ui.control.Image
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CardImg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function ButtonPushed2(app, event)
            
        end

        % Callback function: Button, UIAxes
        function Image2Clicked(app, event)
            % 画像ファイルを選択するためのダイアログを開く
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'});
    
    % ファイルが選択された場合
    if isequal(file, 0)
        disp('User selected Cancel');
    else
        % 画像を読み込む
        img = imread(fullfile(path, file));
        
        % 画像をAxesに表示する
        imshow(img, 'Parent', app.UIAxes);

        app.CardImg = img;
    end

        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            card = ScanController().Ocr(app.CardImg);
            newData = [
                card.Name, card.CompanyName, card.Role, card.Email, card.Other, card.ImgName
                ];
            if size(app.UITable.Data) > 0
                app.UITable.Data = [
                    app.UITable.Data;
                    newData;
                ];
            else
                app.UITable.Data = [
                    newData;
                ];
            end
            
        end

        % Button pushed function: CSVButton
        function CSVButtonPushed(app, event)
            writematrix([ ...
                    "名前", "会社名", "役職", "メールアドレス", "OCR結果", "画像名";
                    app.UITable.Data;...
                ], "cards.csv");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Button.Text = '名刺画像を選択';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'名前'; '会社名'; '役職'; 'メールアドレス'; 'その他'; '画像'};
            app.UITable.RowName = {};
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '認識してリストに追加';
            app.CSVButton = uibutton(app.GridLayout, 'push');
            app.CSVButton.ButtonPushedFcn = createCallbackFcn(app, @CSVButtonPushed, true);
            app.CSVButton.Text = 'CSVでエクスポート';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'arrow.png');
            app.Image2_2 = uiimage(app.GridLayout);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'arrow.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icontact

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end